classdef DatasetUploadApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NDIDatasetWizardUIFigure        matlab.ui.Figure
        FooterPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        MainGridLayout                  matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        IntroGridLayout                 matlab.ui.container.GridLayout
        IntroLabel                      matlab.ui.control.Label
        GridLayout_Step0_C2             matlab.ui.container.GridLayout
        IntroductionTextLabel           matlab.ui.control.Label
        GridLayout_Step0_C3             matlab.ui.container.GridLayout
        GetStartedButton                matlab.ui.control.Button
        DatasetOverviewTab              matlab.ui.container.Tab
        DatasetOverviewGridLayout       matlab.ui.container.GridLayout
        DatasetInformationLabel         matlab.ui.control.Label
        DatasetInformationPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        CommentsDetailsTextArea         matlab.ui.control.TextArea
        CommentsDetailsLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DatasetShortNameLabel           matlab.ui.control.Label
        DatasetBranchTitleLabel         matlab.ui.control.Label
        DatasetBranchTitleEditField     matlab.ui.control.EditField
        ShortNameEditField              matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        AbstractLabel                   matlab.ui.control.Label
        AbstractTextArea                matlab.ui.control.TextArea
        AuthorsTab                      matlab.ui.container.Tab
        AuthorsGridLayout               matlab.ui.container.GridLayout
        AuthorDetailsLabel              matlab.ui.control.Label
        AuthorMainPanel                 matlab.ui.container.Panel
        AuthorMainPanelGridLayout       matlab.ui.container.GridLayout
        AuthorContentLeftGridLayout     matlab.ui.container.GridLayout
        AuthorListBoxLabel              matlab.ui.control.Label
        AuthorListBoxGridLayout         matlab.ui.container.GridLayout
        AuthorListBox                   matlab.ui.control.ListBox
        AuthorListBoxButtonGridLayout   matlab.ui.container.GridLayout
        AddAuthorButton                 matlab.ui.control.Button
        RemoveAuthorButton              matlab.ui.control.Button
        MoveAuthorUpButton              matlab.ui.control.Button
        MoveAuthorDownButton            matlab.ui.control.Button
        AuthorContentCenterGridLayout   matlab.ui.container.GridLayout
        AdditionalDetailsTextArea       matlab.ui.control.TextArea
        AdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        AuthorEmailEditFieldLabel       matlab.ui.control.Label
        AuthorEmailEditField            matlab.ui.control.EditField
        AuthorOrcidGridLayout           matlab.ui.container.GridLayout
        SearchOrcidButton               matlab.ui.control.Button
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierORCIDLabel     matlab.ui.control.Label
        FamilyNameEditField             matlab.ui.control.EditField
        FamilyNameEditFieldLabel        matlab.ui.control.Label
        GivenNameEditField              matlab.ui.control.EditField
        GivenNameEditFieldLabel         matlab.ui.control.Label
        AuthorContentRightGridLayout    matlab.ui.container.GridLayout
        AffiliationListBoxGridLayout    matlab.ui.container.GridLayout
        AffiliationListBoxButtonGridLayout  matlab.ui.container.GridLayout
        AddAffiliationButton            matlab.ui.control.Button
        RemoveAffiliationButton         matlab.ui.control.Button
        MoveAffiliationUpButton         matlab.ui.control.Button
        MoveAffiliationDownButton       matlab.ui.control.Button
        AffiliationTable                matlab.ui.control.Table
        AffiliationsListBoxLabel        matlab.ui.control.Label
        AuthorRoleTree                  matlab.ui.container.CheckBoxTree
        stAuthorNode                    matlab.ui.container.TreeNode
        CustodianNode                   matlab.ui.container.TreeNode
        CorrespondingNode               matlab.ui.container.TreeNode
        AuthorRoleLabel                 matlab.ui.control.Label
        DatasetDetailsTab               matlab.ui.container.Tab
        DatasetDetailsGridLayout        matlab.ui.container.GridLayout
        DatasetDetailsLabel             matlab.ui.control.Label
        DatasetDetailsPanel             matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        RelatedPublicationsLabel        matlab.ui.control.Label
        RelatedPublicationUITable       matlab.ui.control.Table
        AddRelatedPublicationButton     matlab.ui.control.Button
        FundingEditField                matlab.ui.control.EditField
        FundingEditFieldLabel           matlab.ui.control.Label
        VersionInnovationEditField      matlab.ui.control.EditField
        VersionInnovationEditFieldLabel  matlab.ui.control.Label
        FullDocumentationEditField      matlab.ui.control.EditField
        FullDocumentationEditFieldLabel  matlab.ui.control.Label
        VersionIdentifierEditField      matlab.ui.control.EditField
        VersionIdentifierEditFieldLabel  matlab.ui.control.Label
        LicenseDropDown                 matlab.ui.control.DropDown
        LicenseDropDownLabel            matlab.ui.control.Label
        ReleaseDateDatePicker           matlab.ui.control.DatePicker
        ReleaseDateDatePickerLabel      matlab.ui.control.Label
        ExperimentDetailsTab            matlab.ui.container.Tab
        ExperimentDetailsGridLayout     matlab.ui.container.GridLayout
        ExperimentDetailsLabel          matlab.ui.control.Label
        ExperimentDetailsPanel          matlab.ui.container.Panel
        AddTechniqueButton              matlab.ui.control.Button
        SelectedTechniquesListBox       matlab.ui.control.ListBox
        SelectedTechniquesListBoxLabel  matlab.ui.control.Label
        SelectTechniqueDropDown         matlab.ui.control.DropDown
        SelectTechniqueLabel            matlab.ui.control.Label
        SelectTechniqueCategoryDropDown  matlab.ui.control.DropDown
        SelectTechniqueCategoryDropDownLabel  matlab.ui.control.Label
        ExperimentalApproachLabel       matlab.ui.control.Label
        ExperimentalApproachTree        matlab.ui.container.CheckBoxTree
        DataTypeTree                    matlab.ui.container.CheckBoxTree
        RawNode                         matlab.ui.container.TreeNode
        DerivedNode                     matlab.ui.container.TreeNode
        SimulatedNode                   matlab.ui.container.TreeNode
        DataTypeLabel                   matlab.ui.control.Label
        TechniquesEmployedLabel_2       matlab.ui.control.Label
        TechniquesEmployedTree          matlab.ui.container.CheckBoxTree
        SubjectInfoTab                  matlab.ui.container.Tab
        SubjectInfoGridLayout           matlab.ui.container.GridLayout
        SubjectInfoLabel                matlab.ui.control.Label
        SubjectInfoPanel                matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        UITableSubject                  matlab.ui.control.Table
        GridLayout17                    matlab.ui.container.GridLayout
        Panel_8                         matlab.ui.container.Panel
        StrainListBox                   matlab.ui.control.ListBox
        StrainClearButton               matlab.ui.control.Button
        StrainLabel_3                   matlab.ui.control.Label
        AssignStrainButton              matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        AddSpeciesButton                matlab.ui.control.Button
        SpeciesEditField                matlab.ui.control.EditField
        SpeciesClearButton              matlab.ui.control.Button
        SpeciesLabel_2                  matlab.ui.control.Label
        SpeciesListBox                  matlab.ui.control.ListBox
        AssignSpeciesButton             matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        BiologicalSexClearButton        matlab.ui.control.Button
        AssignBiologicalSexButton       matlab.ui.control.Button
        BiologicalSexLabel              matlab.ui.control.Label
        BiologicalSexListBox            matlab.ui.control.ListBox
        ProbeInfoTab                    matlab.ui.container.Tab
        ProbeInfoGridLayout             matlab.ui.container.GridLayout
        ProbeInfoLabel                  matlab.ui.control.Label
        ProbeInfoPanel                  matlab.ui.container.Panel
        UITableProbe                    matlab.ui.control.Table
        SubmitTab                       matlab.ui.container.Tab
        SubmitGridLayout                matlab.ui.container.GridLayout
        SubmitLabel                     matlab.ui.control.Label
        SubmitPanelGridLayout           matlab.ui.container.GridLayout
        SubmissionDescriptionLabel      matlab.ui.control.Label
        SubmissionStatusPanel           matlab.ui.container.Panel
        ErrorTextArea                   matlab.ui.control.TextArea
        ErrorTextAreaLabel              matlab.ui.control.Label
        SubmitFooterGridLayout          matlab.ui.container.GridLayout
        SubmitButton                    matlab.ui.control.Button
        SaveChangesButton               matlab.ui.control.Button
        FooterpanelLabel                matlab.ui.control.Label
        DatasetIdentifierEditField      matlab.ui.control.EditField
        DatasetIdentifierEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Struct holding all the information entered in the app for saving
        % and loading
        DatasetInformation (1,1) struct
        
        % A struct for storing input form apps, like the Author form. Used
        % to keep external forms/figures in memory, but hidden
        UIForm (1,1) struct
        
        DatasetData (1,1) ndi.database.metadata_app.class.DatasetData
        DatasetVersionData (1,1) ndi.database.metadata_app.class.DatasetVersionData
        
        %Step 1
        DatasetBranchTitle
        DatasetShortName
        Abstract
        Comments
        
        %Step 2
        AuthorData (1,1) ndi.database.metadata_app.class.AuthorData
        
        %Step 3
        FullDocumentation
        RelatedPublication
        VersionIdentifier
        DataType
        VersionInnovation
        TechniquesEmployed
        ReleaseDate
        ExperimentalApproach
        License
        Funding

        %Step 4
        SpeciesData (1,1) ndi.database.metadata_app.class.SpeciesData
        SubjectData (1,1) ndi.database.metadata_app.class.SubjectData

        %Step 5
        ProbeData (1,1) ndi.database.metadata_app.class.ProbeData


        % A ndi session
        Session
        
        % A filepath for saving and retrieving the metadata information. It
        % is under Session/.ndi/NDIDatasetUpload
        FilePath

    end

    properties (Access = private)
        Timer
    end
    
    methods (Access = private)

        function saveDatasetInformation(app, fieldName)
            
            if nargin < 2; fieldName = ''; end

            switch fieldName
                case 'Dataset'
                    app.DatasetInformation.Dataset = app.DatasetData.DatasetInfo;
                case 'DatasetVersionData'
                    app.DatasetInformation.DatasetVersion = app.DatasetVersionData.DatasetVersionInfo;

                %Step 1
                case 'DatasetBranchTitle'
                    app.DatasetInformation.DatasetBranchTitle = app.DatasetBranchTitle;
                    app.DatasetData.updateProperty("fullName", app.DatasetBranchTitle);
                    app.DatasetVersionData.updateProperty("fullName", app.DatasetBranchTitle);
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                case 'DatasetShortName'
                    app.DatasetInformation.DatasetShortName = app.DatasetShortName;
                    app.DatasetData.updateProperty("shortName", app.DatasetShortName);
                    app.DatasetVersionData.updateProperty("shortName", app.DatasetShortName);
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                case 'Abstract'
                    app.DatasetInformation.Abstract = app.Abstract;
                    app.DatasetData.updateProperty("description", app.Abstract);
                    app.saveDatasetInformation('Dataset');
                case 'Comments'
                    app.DatasetInformation.Comments = app.Comments;
                    
                %Step 2
                case 'Author'
                    app.DatasetInformation.Author = app.AuthorData.AuthorList;
                    app.DatasetData.updateProperty("author", app.AuthorData.AuthorList);
                    app.saveDatasetInformation('Dataset');
                    app.saveDatasetInformation('DatasetVersionData');
                %Step 3
                case 'FullDocumentation'
                    app.DatasetInformation.FullDocumentation = app.FullDocumentation;
                    app.DatasetVersionData.updateProperty("fullDocumentation", app.FullDocumentation);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'RelatedPublication'
                    app.DatasetInformation.RelatedPublication = app.RelatedPublication;
                    app.DatasetVersionData.updateProperty("relatedPublication", app.RelatedPublication);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'VersionIdentifier'
                    app.DatasetInformation.VersionIdentifier = app.VersionIdentifier;
                    app.DatasetVersionData.updateProperty("versionIdentifier", app.VersionIdentifier);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'DataType'
                    app.DatasetInformation.DataType = app.DataType;
                    app.DatasetVersionData.updateProperty("dataType", app.DataType);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'VersionInnovation'
                    app.DatasetInformation.VersionInnovation = app.VersionInnovation;
                    app.DatasetVersionData.updateProperty("versionInnovation", app.VersionInnovation);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'TechniquesEmployed'
                    app.DatasetInformation.TechniquesEmployed = app.TechniquesEmployed;
                    app.DatasetVersionData.updateProperty("technique", app.TechniquesEmployed);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'ReleaseDate'
                    app.DatasetInformation.ReleaseDate = app.ReleaseDate;
                    app.DatasetVersionData.updateProperty("releaseDate", app.ReleaseDate);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'ExperimentalApproach'
                    app.DatasetInformation.ExperimentalApproach = app.ExperimentalApproach;
                    app.DatasetVersionData.updateProperty("experimentalApproach", app.ExperimentalApproach);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'License'
                    app.DatasetInformation.License = app.License;
                    app.DatasetVersionData.updateProperty("license", app.License);
                    app.saveDatasetInformation('DatasetVersionData');
                case 'Funding'
                    app.DatasetInformation.Funding = app.Funding;
                    app.DatasetVersionData.updateProperty("funding", app.Funding);
                    app.saveDatasetInformation('DatasetVersionData');

                %Step 4
                case 'Species'
                    app.DatasetInformation.Species = app.SpeciesData.SpeciesList;
                case 'Subject'
                    app.DatasetInformation.Subjects = app.SubjectData.SubjectList;

                %Step 5
                case 'Probe'
                    app.DatasetInformation.Probe = app.ProbeData.ProbeList;
                
            end
            
            savePath = app.getDatasetFilepath();
            datasetInformation = app.DatasetInformation;
            disp(datasetInformation);
            save(savePath, "datasetInformation")
        end

        % function loadDatasetInformation(app)
        %     loadPath = app.getDatasetFilepath();
        %     if isfile(loadPath)
        %         S = load(loadPath, "datasetInformation");
        %         app.DatasetInformation = S.datasetInformation;
        % 
        %         if isfield(app.DatasetInformation, 'Author')
        %             if numel(app.DatasetInformation.Author) > 0
        %                 S = app.DatasetInformation.Author(1);
        %                 app.fillAuthorInputFieldsFromStruct(S)
        %                 app.AuthorData.AuthorList = app.DatasetInformation.Author;
        %                 app.updateAuthorListbox()
        %             end
        %         end
        %     end
        % end

        % Get the index for the selected author in the author listbox
        function authorIndex = getSelectedAuthorIndex(app)
            isSelected = strcmp(app.AuthorListBox.Items, app.AuthorListBox.Value);
            authorIndex = find(isSelected);
        end

        % Validate person property against openMINDS
        function updatePerson(app, propertyName, propertyValue)
        % Try to create an openMINDS Person with given property value and
        %show error if the the information is not valid. Note: requires
        %openMINDS_MATLAB to be on path.
            try
                p = openminds.core.Person(propertyName, propertyValue);
            catch ME
                uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid input')
            end
        end
      
        % Update author input fields based on a struct of author details
        function fillAuthorInputFieldsFromStruct(app, S)
            % Note: Not all fields are handled yet
            app.GivenNameEditField.Value = S.givenName;
            app.FamilyNameEditField.Value = S.familyName;
            app.AuthorEmailEditField.Value = S.contactInformation.email;
            app.DigitalIdentifierEditField.Value = S.digitalIdentifier.identifier;
            app.AffiliationTable.Data = [];
            afflist = S.affiliation.getAffiliationList();
            for i = 1:numel(afflist)
                app.addAffiliationToTable(afflist(i));
            end

            %cbt = S.AuthorRole;
            nodeText = {app.AuthorRoleTree.Children.Text};
            tf = ismember(nodeText, S.authorRole);

            if any(tf)
                app.AuthorRoleTree.CheckedNodes = app.AuthorRoleTree.Children(tf);
            else
                app.AuthorRoleTree.CheckedNodes = [];
            end
            app.AdditionalDetailsTextArea.Value = S.additional;
        end

        % Update the author listbox with the full names of all existing authors
        function updateAuthorListbox(app)
            S = app.AuthorData.AuthorList;
            fullNames = arrayfun(@(i) app.AuthorData.getAuthorName(i), 1:numel(S), 'UniformOutput', false);
            app.AuthorListBox.Items = fullNames;
            app.AuthorListBox.Value = fullNames{1};
        end

        function onAuthorNameChanged(app, fieldName, value)
            
            %app.updatePerson(fieldName, value)
            authorIndex = app.getSelectedAuthorIndex();
            if isempty(authorIndex); authorIndex = 1; end

            app.AuthorData.updateProperty(fieldName, value, authorIndex)
            fullName = app.AuthorData.getAuthorName(authorIndex);

            app.AuthorListBox.Items{authorIndex} = fullName;
            app.AuthorListBox.Value = fullName;
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Check if any ORCID belongs to someone perfectly matching the
        % given name.
        function checkOrcidMatch(app, fullName)
            try
                orcid = ndi.database.metadata_app.fun.getOrcId(fullName);
                if ~isempty(orcid)
                    orcidLink = sprintf("https://orcid.org/%s", orcid);
                    msg = sprintf('The following ORCID <a href="%s">%s</a> was found matching the given author name. Please use the link above to check if the information matches the intended author', orcidLink, orcid);
                    answer = uiconfirm(app.NDIDatasetWizardUIFigure, msg, "Review ORCID", "Options", {'Confirm', 'Reject'}, 'CancelOption', 'Reject', 'Interpreter', 'html');
                    if strcmp( answer, 'Confirm' )
                        app.DigitalIdentifierEditField.Value = orcidLink;

                        authorIndex = app.getSelectedAuthorIndex();
                        app.AuthorData.updateIdentifier(orcidLink, authorIndex);
                        app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                        app.saveDatasetInformation('Author');
                    end
                end 
            catch
                % Pass for now
            end
        end

        % Add author details to the authors table.
        function addAffiliationToTable(app,aff)
            S = struct;
            S.name = aff.memberOf.fullName;
            newRowData = struct2table(S,'AsArray', true);
            app.AffiliationTable.Data = cat(1, app.AffiliationTable.Data, newRowData);
        end

        % Replace information for specified author in the author table
        function replaceAffiliationInTable(app, S, affiliationIndex)
            newRowData = struct2table(S,'AsArray', true);
            newRowData.identifier = []; 
            app.AffiliationTable.Data(affiliationIndex, :) = newRowData;
        end

        % function deleteAffiliationInArray(app, authorIndex, affiliationIndex)
        %     app.AuthorData.AuthorList(authorIndex).Affiliation(affiliationIndex) = [];
        % end 

        % Open (external app) form where user can enter author details
        function openAffiliationForm(app, affiliationInfo, affiliationIndex)
        %openAffiliationForm Open Affiliation form where user can enter Affiliation details
            if ~isfield(app.UIForm, 'Affiliation')
                app.UIForm.Affiliation = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
            else
                app.UIForm.Affiliation.Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(affiliationInfo)
                % Update form information if we are editing an affiliation
                authorIndex = app.getSelectedAuthorIndex();
                app.AuthorData.updateProperty('affiliation', affiliationInfo, authorIndex)
                app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                % app.AuthorData.setAffiliation(authorIndex, affiliationInfo);
                % app.UIForm.Author.setAuthorDetails(authorInfo);
            end

            app.UIForm.Affiliation.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Affiliation.getAffiliationDetails();
            authorIndex = app.getSelectedAuthorIndex();
            
            % Update data in table if user pressed save.
            mode = app.UIForm.Affiliation.FinishState;
            if mode == "Save"
                if nargin > 1
                    app.replaceAffiliationInTable(S, affiliationIndex)
                else
                    app.addAffiliationToTable(S);
                end
            end
            
            app.AuthorData.addAffiliation(authorIndex, S.name, S.identifier);
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');

            app.UIForm.Affiliation.reset()
            app.UIForm.Affiliation.Visible = 'off'; % Hide the form (for later reuse)
        end


        % Replace information for specified author in the author table
        function replaceProbeInTable(app, probeIndex, probe)
            newRowData = struct2table(probe.toTableStruct(),'AsArray', true);
            cellData = table2cell(newRowData);
            app.UITableProbe.Data(probeIndex, :) = cellData;
        end

        function openFundingForm(app, fundingName)
        %openAffiliationForm Open Affiliation form where user can enter Affiliation details
            if ~isfield(app.UIForm, 'Funding')
                app.UIForm.Funding = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
            else
                app.UIForm.Funding.Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(fundingName)
                % app.Funding.updateProperty('Affiliation', affiliationInfo, authorIndex)
                % app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                % app.AuthorData.setAffiliation(authorIndex, affiliationInfo);
                % app.UIForm.Author.setAuthorDetails(authorInfo);
                app.UIForm.Funding.setName(fundingName);
            end

            app.UIForm.Funding.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Funding.getAffiliationDetails();
           
            % Update data in table if user pressed save.
            mode = app.UIForm.Funding.FinishState;
            if mode == "Save"
                app.FundingEditField.Value = S.AffiliationName;
            end
            app.Funding = S;
            app.saveDatasetInformation('Funding');

            app.UIForm.Funding.reset()
            app.UIForm.Funding.Visible = 'off'; % Hide the form (for later reuse)
        end

        % Open (external app) form where user can enter probe details
        function openProbeForm(app, probeType, probeIndex, probe)
        %openProbeForm Open probe form where user can enter probe details
            switch probeType
                case "Electrode"
                    if ~isfield(app.UIForm, 'Electrode')
                        app.UIForm.Electrode = ndi.database.metadata_app.Apps.ElectrodeForm();
                    else
                        app.UIForm.Electrode.Visible = 1;
                    end
                    form = app.UIForm.Electrode;
                case "Pipette"
                    if ~isfield(app.UIForm, 'Pipette')
                        app.UIForm.Pipette = ndi.database.metadata_app.Apps.PipetteForm();
                    else
                        app.UIForm.Pipette.Visible = 1;
                    end
                    form = app.UIForm.Pipette;
                if nargin > 3
                    form.setProbeDetails(probe);
                end
            end

            if nargin > 3 && ~isempty(probe)
                % Update form information if we are editing an probe
                form.setProbeDetails(probe);
            end

            form.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            newProbe = form.getProbeDetails();

            % Update data in table if user pressed save.
            mode = form.FinishState;
            if mode == "Save"
                app.ProbeData.replaceProbe(probeIndex, newProbe);
                app.replaceProbeInTable(probeIndex, newProbe);
                app.saveDatasetInformation('Probe');
            end

            form.reset()
            form.Visible = 'off'; % Hide the form (for later reuse)
        end
        
        function populateTechniqueCategoryDropdown(app)
                
            dsv = openminds.core.DatasetVersion();
            techniqueClass = class(dsv.technique);
            allowedTypes = eval( [techniqueClass '.ALLOWED_TYPES'] );
            allowedTypes = replace(allowedTypes, 'openminds.controlledterms.', '');
            app.SelectTechniqueCategoryDropDown.Items = allowedTypes; 
        end
        
        function populateTechniqueDropdown(app)
            schemaName = app.SelectTechniqueCategoryDropDown.Value;
            options = eval(sprintf('openminds.controlledterms.%s.CONTROLLED_INSTANCES', schemaName));
            app.SelectTechniqueDropDown.Items = options;
        end
    end
    
    methods (Access = private)
        
        function setFigureMinSize(app)
            isMatch = false;
            drawnow
            while ~any(isMatch)
                windowList = matlab.internal.webwindowmanager.instance.findAllWebwindows();
                isMatch = strcmp({windowList.Title}, app.NDIDatasetWizardUIFigure.Name);
            end
            window = windowList(isMatch);
            window.setMinSize([640 480])
        end

        function filePath = getDatasetFilepath(app)

            filePath = app.FilePath;
            if isempty(filePath)
                filePath = fullfile(userpath, 'NDIDatasetUpload', 'dataset.mat');
            end
        end

        function resetNameIn(app, name, numSeconds)
        % Use a time to reset figure name
            if ~isempty(app.Timer)
                stop(app.Timer)
                delete(app.Timer)
                app.Timer = [];
            end

            app.Timer = timer();
            app.Timer.TimerFcn = @(s,e)app.updateName(name);
            app.Timer.StartDelay = 5;
            start(app.Timer)
        end

        function updateName(app, name)
        % Update figure name and delete timer.
            app.NDIDatasetWizardUIFigure.Name = name;
            stop(app.Timer)
            delete(app.Timer)
            app.Timer = [];
        end
    
        function changeTab(app, newTab)
            app.TabGroup.SelectedTab = newTab;
            app.onTabChanged()
        end

        function onTabChanged(app, selectedTab)
        % onTabChanged - Callback for when current tab is changed.
            
            if nargin < 2 
                selectedTab = app.TabGroup.SelectedTab;
            end

            if selectedTab == app.TabGroup.Children(1) ...
                    || selectedTab == app.TabGroup.Children(end)
                
                app.FooterPanel.Visible = 'off';
                app.FooterPanel.Parent = app.NDIDatasetWizardUIFigure;

                app.MainGridLayout.RowHeight = {'1x'};
            else
                app.FooterPanel.Visible = 'on';
                app.MainGridLayout.RowHeight = {'1x', 63};
                app.FooterPanel.Parent = app.MainGridLayout;
                app.FooterPanel.Layout.Row = 2;
                app.FooterPanel.Layout.Column = 1;
            end
        end

        function updateSubjectTableColumData(app, columnName, newValue)
                
            % Get the selected row and column in the table
            selectedRows = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:numel(selectedRows)

                subjectIndex = selectedRows(i);
                subjectName = app.UITableSubject.Data{selectedRows(i), 'Subject'};
                % subjectIndex = app.SubjectData.getIndex(subjectName); %Question: Would this ever be different from selected row?

                if ~isempty(newValue) && ~isempty(subjectName) && subjectIndex ~= -1          
                    switch columnName
                        case 'BiologicalSex'
                            app.SubjectData.SubjectList(subjectIndex).BiologicalSexList = {newValue};
                            
                        case 'Species'
                            species_name = app.SpeciesListBox.Value;
                            species = app.SpeciesData.getItem(species_name);
                            if ~app.SubjectData.biologicalSexSelected(subjectName) && ~isempty(species) && isa(species, 'ndi.database.metadata_app.class.Species')
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please fill the biological sex for the subjects selected.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing biological sex');
                                break;
                            else
                                app.SubjectData.SubjectList(subjectIndex).SpeciesList = species;
                            end

                        case 'Strain'
                            strainName = app.StrainListBox.Value;
                            if isempty(strainName)
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please select a valid strain.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing strain');
                                break;
                            end
                            if ~app.SubjectData.SpeciesSelected(subjectName)
                                app.SubjectData = prevData;
                                data = app.SubjectData.formatTable();
                                app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                                errMessage = sprintf('Please fill the species for the subjects selected.');
                                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing species');
                                break;
                            else
                                app.SubjectData.SubjectList(subjectIndex).addStrain(strainName);
                            end
                     
                    end
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                end
            end
            app.saveDatasetInformation('Subject');
        end

        function deleteSubjectTableColumData(app, columnName)
                
            % Get the selected row and column in the table
            selectedRows = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:numel(selectedRows)

                subjectIndex = selectedRows(i);
                subjectName = app.UITableSubject.Data{selectedRows(i), 'Subject'};

                if ~isempty(subjectName) && subjectIndex ~= -1          
                    switch columnName
                        case 'BiologicalSex'
                            app.SubjectData.SubjectList(subjectIndex).deleteBiologicalSex();
                            
                        case 'Species'
                            app.SubjectData.SubjectList(subjectIndex).deleteSpeciesList();

                        case 'Strain'
                            app.SubjectData.SubjectList(subjectIndex).deleteStrainList();
                    end
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'invalid selection');
                end
            end
            app.saveDatasetInformation('Subject');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sessionObject, filePath)
            
            arguments 
                app (1,1) ndi.database.metadata_app.Apps.DatasetUploadApp
                sessionObject (1,:) ndi.session = ndi.session.empty % Todo: should be required.
                filePath (1,:) string = string.empty
            end
        
            if ~isempty(sessionObject)
                app.Session = sessionObject;
            end
        
            if ~isempty(filePath)
                app.FilePath = filePath;
            end

            app.FooterPanel.Visible = 'off';

            % app.setFigureMinSize()
            app.UITableSubject.SelectionType = "row";

             %check if app has a Session field
            if ~isempty(app.Session)
                subjectData = ndi.database.metadata_app.fun.loadSubjects(app.Session);
                app.SubjectData = subjectData;
                subjectTableData = subjectData.formatTable();
                app.UITableSubject.Data = struct2table(subjectTableData, 'AsArray', true);

                probeData = ndi.database.metadata_app.fun.loadProbes(app.Session);
                app.ProbeData = probeData;
                probeTableData = probeData.formtaTable();
                app.UITableProbe.Data = struct2table(probeTableData, 'AsArray', true);
            end

            try
                ndi.fun.assertAddonOnPath("openMINDS Metadata Models", 'RequiredFor', 'NDI Dataset Wizard')
            catch ME
                delete(app)
                throwAsCaller(ME)
            end

            ndi.database.metadata_app.fun.loadExperimentalApproach(app.ExperimentalApproachTree);
            ndi.database.metadata_app.fun.loadTechniquesEmployed(app.TechniquesEmployedTree);

            app.populateTechniqueCategoryDropdown()
            app.populateTechniqueDropdown()
          
            % app.loadDatasetInformation()
        end

        % Close request function: NDIDatasetWizardUIFigure
        function NDIDatasetWizardUIFigureCloseRequest(app, event)
            formNames = fieldnames(app.UIForm);
            for i = 1:numel(formNames)
                thisName = formNames{i};
                delete(app.UIForm.(thisName))
            end
            delete(app)
        end

        % Button pushed function: AddAuthorButton
        function AddAuthorButtonPushed2(app, event)
            % Add item to listbox
            numAuthors = numel(app.AuthorListBox.Items);
            app.AuthorListBox.Items{end+1} = sprintf('Author %d', numAuthors+1);
            app.AuthorListBox.Value = app.AuthorListBox.Items{end};
            app.AuthorListBoxValueChanged();
        end

        % Button pushed function: RemoveAuthorButton
        function RemoveAuthorButtonPushed(app, event)
            % Remove item from listbox
            isSelected = strcmp(app.AuthorListBox.Items, app.AuthorListBox.Value);
            
            newItems = app.AuthorListBox.Items(~isSelected);
            
            % Remove author from list in author data
            try
                app.AuthorData.removeItem(find(isSelected))
            catch
                % A placeholder author was removed.
            end
            
            % Update items in listbox
            app.AuthorListBox.Value = newItems{1};
            app.AuthorListBox.Items = newItems;

            % Update all input fields to reflect new selection 
            S = app.AuthorData.getItem(1);
            app.fillAuthorInputFieldsFromStruct(S)

            app.saveDatasetInformation('Author')
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
        end

        % Value changed function: GivenNameEditField
        function GivenNameEditFieldValueChanged(app, event)
            newValue = app.GivenNameEditField.Value;
            app.onAuthorNameChanged('givenName', newValue)

            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            app.checkOrcidMatch(fullName) % Only do for value changed
        end

        % Value changing function: GivenNameEditField
        function GivenNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.onAuthorNameChanged('givenName', changingValue)
        end

        % Value changed function: FamilyNameEditField
        function FamilyNameEditFieldValueChanged(app, event)
            newValue = app.FamilyNameEditField.Value;
            app.onAuthorNameChanged('familyName', newValue)
               
            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            app.checkOrcidMatch(fullName)
        end

        % Value changing function: FamilyNameEditField
        function FamilyNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.onAuthorNameChanged('familyName', changingValue)
        end

        % Value changed function: AuthorEmailEditField
        function AuthorEmailEditFieldValueChanged(app, event)
            value = app.AuthorEmailEditField.Value;
            try
                mustBeValidEmail(value)
            catch ME
                uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid email')
            end
            %app.updatePerson('contactInformation', contactInformation)
            
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateEmail(value, authorIndex)
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.DatasetVersionData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Value changed function: DigitalIdentifierEditField
        function DigitalIdentifierEditFieldValueChanged(app, event)
            value = app.DigitalIdentifierEditField.Value;
            if ~isempty(value)
                try
                    if ~contains(value, 'https://orcid.org/')
                        value = sprintf('https://orcid.org/%s', value);
                    end
                    orcid = openminds.core.ORCID('identifier',value);
                    app.updatePerson('digitalIdentifier', orcid)
                    app.DigitalIdentifierEditField.Value = value;
                catch ME
                    errMessage = sprintf('The entered value is not a valid ORCID. For examples of valid ORCID, please see this <a href=https://support.orcid.org/hc/en-us/articles/360006897674-Structure-of-the-ORCID-Identifier#3-some-sample-orcid-ids>link</a>');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid ORCID', 'Interpreter', 'html')
                    %uialert(app.NDIDatasetWizardUIFigure, ME.message, 'Invalid input')
                end
            end
            
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateIdentifier(value, authorIndex)
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author')
        end

        % Value changed function: AuthorListBox
        function AuthorListBoxValueChanged(app, event)
            authorIndex = app.getSelectedAuthorIndex();

            S = app.AuthorData.getItem(authorIndex);
            app.fillAuthorInputFieldsFromStruct(S) % Update the author fields
        end

        % Button pushed function: SearchOrcidButton
        function SearchOrcidButtonPushed(app, event)
            authorIndex = app.getSelectedAuthorIndex();
            fullName = app.AuthorData.getAuthorName(authorIndex);
            apiQueryUrl = ndi.database.metadata_app.fun.getOrcIdSearchUrl(fullName);
            web(apiQueryUrl)
        end

        % Callback function: AuthorRoleTree
        function AuthorRoleTreeCheckedNodesChanged(app, event)
           
            checkedNodes = event.CheckedNodes;
            if ~isempty(checkedNodes)
                selectedAuthorRoles = {checkedNodes.Text};
            else
                selectedAuthorRoles = '';
            end

            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateProperty('authorRole', selectedAuthorRoles, authorIndex);
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Button pushed function: AddAffiliationButton
        function AddAffiliationButtonPushed(app, event)
            app.openAffiliationForm();
        end

        % Button pushed function: RemoveAffiliationButton
        function RemoveAffiliationButtonPushed(app, event)
            if ~isempty(app.AffiliationTable.Data)
                selectedIndices = app.AffiliationTable.Selection(1);
                app.AffiliationTable.Data(selectedIndices,:) = [];
                authorIndex = app.getSelectedAuthorIndex();
                app.AuthorData.removeAffiliation(authorIndex, selectedIndices);
                app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
                app.saveDatasetInformation('Author');
            end
        end

        % Value changed function: AdditionalDetailsTextArea
        function AdditionalDetailsValueChanged(app, event)
            value = app.AdditionalDetailsTextArea.Value;
            authorIndex = app.getSelectedAuthorIndex();
            app.AuthorData.updateProperty('additional', value, authorIndex);
            app.DatasetData.updateProperty('Author',app.AuthorData.AuthorList);
            app.saveDatasetInformation('Author');
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            app.onTabChanged(app.DatasetOverviewTab)
            app.TabGroup.SelectedTab = app.DatasetOverviewTab;
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard (Saving...)';
            app.saveDatasetInformation()
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard (Saved changes)';
            app.resetNameIn('NDI Dataset Wizard', 3)
        end

        % Callback function
        function SubmitButtonPushed(app, event)
            ndi.database.fun.dataset_metadata(app.Session, app.FilePath, 'action', 'submit', 'AuthorData', app.AuthorData);
            % app.AuthorData
        end

        % Value changed function: DatasetBranchTitleEditField
        function DatasetBranchTitleValueChanged(app, event)
            value = app.DatasetBranchTitleEditField.Value;
            app.DatasetData.updateProperty("FullName",value);
            app.ShortNameEditField.Value = ndi.database.metadata_app.fun.generateShortName(value, 3);
            app.DatasetData.updateProperty("ShortName",value);
            
            app.DatasetBranchTitle = value;
            app.saveDatasetInformation('DatasetBranchTitle');
            app.DatasetShortName = app.ShortNameEditField.Value;
            app.saveDatasetInformation('DatasetShortName');
        end

        % Value changed function: ShortNameEditField
        function DatasetShortNameValueChanged(app, event)
            value = app.ShortNameEditField.Value;
            app.DatasetData.updateProperty("ShortName",value);

            app.DatasetShortName = app.ShortNameEditField.Value;
            app.saveDatasetInformation('DatasetShortName');
        end

        % Value changed function: AbstractTextArea
        function AbstractValueChanged(app, event)
            value = app.AbstractTextArea.Value;
            app.DatasetData.updateProperty("Description",value);

            app.Abstract = value;
            app.saveDatasetInformation('Abstract');
        end

        % Value changed function: CommentsDetailsTextArea
        function CommentsDetailsValueChanged(app, event)
            value = app.CommentsDetailsTextArea.Value;
            app.Comments = value;
            app.saveDatasetInformation('Comments');
        end

        % Value changed function: LicenseDropDown
        function LicenseDropDownValueChanged(app, event)
            value = app.LicenseDropDown.Value;
            license = ndi.database.metadata_app.class.License(value);
            app.License = license;
            app.saveDatasetInformation('License');
        end

        % Value changed function: FullDocumentationEditField
        function FullDocumentationValueChanged(app, event)
            value = app.FullDocumentationEditField.Value;
            app.FullDocumentation = value;
            app.saveDatasetInformation('FullDocumentation');
        end

        % Value changed function: FundingEditField
        function FundingValueChanged(app, event)
            value = app.FundingEditField.Value;
            app.openFundingForm(value);
        end

        % Button pushed function: AddRelatedPublicationButton
        function AddRelatedPublicationButtonPushed(app, event)
            currentData = app.RelatedPublicationUITable.Data;
            newRow = {'', '', '', ''};
            currentData = [currentData; newRow];
            app.RelatedPublicationUITable.Data = currentData;
            app.RelatedPublication = currentData;
        end

        % Cell edit callback: RelatedPublicationUITable
        function RelatedPublicationCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            currentData = app.RelatedPublicationUITable.Data;
            app.RelatedPublication = currentData;
            app.saveDatasetInformation('RelatedPublication');
        end

        % Value changed function: VersionIdentifierEditField
        function VersionIdentifierValueChanged(app, event)
            value = app.VersionIdentifierEditField.Value;
            app.VersionIdentifier = value;
            app.saveDatasetInformation('VersionIdentifier');
        end

        % Value changed function: VersionInnovationEditField
        function VersionInnovationValueChanged(app, event)
            value = app.VersionInnovationEditField.Value;
            disp(value)
            app.VersionInnovation = value;
            app.saveDatasetInformation('VersionInnovation');
        end

        % Callback function: DataTypeTree
        function DataTypeTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            if ~isempty(checkedNodes)
                selectedDataTypes = {checkedNodes.Text};
            else
                selectedDataTypes = '';
            end
            app.DataType = selectedDataTypes;
            app.saveDatasetInformation('DataType');
        end

        % Callback function: TechniquesEmployedTree
        function TechniqueTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            if ~isempty(checkedNodes)
                selectedTechniques = {checkedNodes.Text};
            else
                selectedTechniques = '';
            end
            app.TechniquesEmployed = selectedTechniques;
            app.saveDatasetInformation('TechniquesEmployed');
        end

        % Callback function: ExperimentalApproachTree
        function ExperimentTreeCheckedNodesChanged(app, event)
            checkedNodes = event.CheckedNodes;
            if ~isempty(checkedNodes)
                selectedExperimentalApproach = {checkedNodes.Text};
            else
                selectedExperimentalApproach = '';
            end
            app.ExperimentalApproach = selectedExperimentalApproach;
            app.saveDatasetInformation('ExperimentalApproach');
        end

        % Value changed function: ReleaseDateDatePicker
        function ReleaseDateValueChanged(app, event)
            value = app.ReleaseDateDatePicker.Value;
            app.ReleaseDate = value;
            app.saveDatasetInformation('ReleaseDate');
        end

        % Button pushed function: AddSpeciesButton
        function AddSpeciesButtonPushed(app, event)
            value = app.SpeciesEditField.Value;
            [~, uuid] = ndi.database.metadata_app.fun.SearchSpecies(value);
            if (uuid == -1)
                errMessage = sprintf('The entered value is not a valid scientific name, common name or synonym.');
                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid species name');
            else
                [name, ontology_identifier, synonym] = ndi.database.metadata_app.fun.getSpeciesInfo(uuid);
                app.NameEditField_4.Value = name;
                app.OntologyIdentifierEditField.Value = ontology_identifier;
                app.SynonymEditField_3.Value = synonym;
                app.SpeciesData.addItem(name,synonym,ontology_identifier);
                app.saveDatasetInformation('Species');
                if (numel(app.SpeciesListBox.Items)) == 0
                    app.SpeciesListBox.Items = name;
                else
                    app.SpeciesListBox.Items{end+1} = sprintf('%s', name);
                end
                
                app.SpeciesListBox.Value = app.SpeciesListBox.Items{end};
            end
        
        end

        % Button pushed function: AssignSpeciesButton
        function AssignSpeciesButtonPushed(app, event)
            name = app.SpeciesListBox.Value;
            species = app.SpeciesData.getItem(name);

            subjectSelections = app.UITableSubject.Selection;
            prevData = app.SubjectData;

            for i = 1:size(subjectSelections,1)
                selectedRow = app.UITableSubject.Selection(i,1);
                selectedColumn = app.UITableSubject.Selection(i,2);
                if ~selectedColumn == 1
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid subject');
                    break;
                end

                subjectName = app.UITableSubject.Data(selectedRow, selectedColumn).Subject;
                if isempty(subjectName) || (app.SubjectData.getIndex(subjectName) == -1)
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid subject');
                    break;
                end
                
                if ~app.SubjectData.biologicalSexSelected(subjectName)
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please fill the biological sex for the subjects selected.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'missing biological sex');
                    break;
                end

                if ~isempty(species) && isa(species, 'ndi.database.metadata_app.class.Species')
                    index = app.SubjectData.getIndex(subjectName);
                    app.SubjectData.SubjectList(index).SpeciesList = species;
                    app.saveDatasetInformation('Subject');
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid species.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Missing species');
                    % msgbox('Please select a valid biological sex and subject.');
                    break;
                end
            end

        end

        % Button pushed function: AssignBiologicalSexButton
        function AssignBiologicalSexButtonPushed(app, event)
            
            biologicalSex = app.BiologicalSexListBox.Value;    
            
            % Get the selected row and column in the table
            subjectSelections = app.UITableSubject.Selection;
            prevData = app.SubjectData;
            
            for i = 1:size(subjectSelections,1)
                selectedRow = app.UITableSubject.Selection(i,1);
                %selectedColumn = app.UITableSubject.Selection(i,2);
                subjectName = app.UITableSubject.Data{selectedRow, 'Subject'};
                
                % Check if a biological sex is selected and a valid subject is chosen
                if ~isempty(biologicalSex) && ~isempty(subjectName) && ~(app.SubjectData.getIndex(subjectName) == -1)
                    % Assign the selected biological sex to the corresponding row and "Biological Sex" column
                    index = app.SubjectData.getIndex(subjectName);
                    app.SubjectData.SubjectList(index).BiologicalSexList = {biologicalSex};
                    app.saveDatasetInformation('Subject');
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                else
                    app.SubjectData = prevData;
                    data = app.SubjectData.formatTable();
                    app.UITableSubject.Data = struct2table(data, 'AsArray', true);
                    % Display an error message if the conditions are not met
                    errMessage = sprintf('Please select a valid biological sex and subject.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Invalid biological sex or subject');
                    % msgbox('Please select a valid biological sex and subject.');
                end
            end
        end

        % Value changed function: BiologicalSexListBox
        function BiologicalSexListBoxValueChanged(app, event)
            % value = app.BiologicalSexListBox.Value;
            % app.updateSubjectTableColumData('BiologicalSex', value)
        end

        % Clicked callback: BiologicalSexListBox
        function BiologicalSexListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('BiologicalSex', value);
        end

        % Value changed function: SpeciesListBox
        function SpeciesListBoxValueChanged(app, event)
            % value = app.SpeciesListBox.Value;
            % app.updateSubjectTableColumData('Species', value);
        end

        % Clicked callback: SpeciesListBox
        function SpeciesListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('Species', value);
        end

        % Value changed function: StrainListBox
        function StrainListBoxValueChanged(app, event)
            % value = app.StrainListBox.Value;
            % app.updateSubjectTableColumData('Strain', value)
        end

        % Clicked callback: StrainListBox
        function StrainListBoxClicked(app, event)
            drawnow;
            value = event.Source.Value;
            app.updateSubjectTableColumData('Strain', value);
        end

        % Button pushed function: StrainClearButton
        function StrainClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("Strain");
        end

        % Button pushed function: SpeciesClearButton
        function SpeciesClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("Species");
        end

        % Button pushed function: BiologicalSexClearButton
        function BiologicalSexClearButtonPushed(app, event)
            app.deleteSubjectTableColumData("BiologicalSex");
        end

        % Callback function
        function ProbeListBoxValueChanged(app, event)
            value = app.ProbeListBox.Value;
            app.TypeofProbeListBox.Value = {};
        end

        % Callback function
        function UITableProbeCellEdit(app, event)
            indices = event.Indices;
            probeType = event.NewData;
            probeIndex = indices(1);
            if ~app.ProbeData.probeExist(probeIndex)
                app.openProbeForm(probeType,probeIndex);
            else
                message = 'You have already select the probe type. Do you really want to change it?';
                title = 'Confirm selection';
                userResponse = uiconfirm(app.NDIDatasetWizardUIFigure, message, title, 'Options', {'Yes', 'No'}, 'DefaultOption', 'No');
                % Check the user's response
                if strcmp(userResponse, 'Yes')
                    app.openProbeForm(probeType,probeIndex);
                else
                    app.UITableProbe.Data{indices(1),indices(2)} = eventdata.PreviousData;
                end
            end            
        end

        % Double-clicked callback: UITableProbe
        function UITableProbeDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            classType = app.ProbeData.ProbeList{displayRow}.ClassType;
            app.openProbeForm(classType,displayRow, app.ProbeData.ProbeList{displayRow});
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.onTabChanged(selectedTab)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            currentTab = app.TabGroup.SelectedTab;
            tabIdx = find( ismember( app.TabGroup.Children, currentTab ) );
            newTabIdx = tabIdx - 1;
            newTab = app.TabGroup.Children(newTabIdx);
            
            app.onTabChanged(newTab)
            drawnow
            app.TabGroup.SelectedTab = newTab;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            currentTab = app.TabGroup.SelectedTab;
            tabIdx = find( ismember( app.TabGroup.Children, currentTab ) );
            newTabIdx = tabIdx + 1;
            newTab = app.TabGroup.Children(newTabIdx);
            
            app.onTabChanged(newTab)
            drawnow
            app.TabGroup.SelectedTab = newTab;
        end

        % Value changed function: SelectTechniqueCategoryDropDown
        function SelectTechniqueCategoryDropDownValueChanged(app, event)
            value = app.SelectTechniqueCategoryDropDown.Value;
            app.populateTechniqueDropdown()
        end

        % Button pushed function: AddTechniqueButton
        function AddTechniqueButtonPushed(app, event)
            
            techniqueCategory = app.SelectTechniqueCategoryDropDown.Value;
            techniqueName = app.SelectTechniqueDropDown.Value;

            %technique = strjoin({techniqueCategory, techniqueName}, ' - ');
            technique = sprintf('%s (%s)', techniqueName, techniqueCategory);
            app.SelectedTechniquesListBox.Items{end+1} = technique;
            
            % Todo: Save to datasetInformation
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NDIDatasetWizardUIFigure = uifigure('Visible', 'off');
            app.NDIDatasetWizardUIFigure.Name = 'NDI Dataset Wizard';
            app.NDIDatasetWizardUIFigure.CloseRequestFcn = createCallbackFcn(app, @NDIDatasetWizardUIFigureCloseRequest, true);
            app.DatasetIdentifierEditFieldLabel = uilabel(app.NDIDatasetWizardUIFigure);
            app.DatasetIdentifierEditFieldLabel.Text = 'Dataset Identifier';
            app.DatasetIdentifierEditField = uieditfield(app.NDIDatasetWizardUIFigure, 'text');
            app.DatasetIdentifierEditField.InputType = 'alphanumerics';
            app.DatasetIdentifierEditField.Tooltip = {'Auto-generated digital nametag for your dataset, no input required'};
            app.DatasetIdentifierEditField.Value = 'GENERATE';
            app.FooterpanelLabel = uilabel(app.NDIDatasetWizardUIFigure);
            app.FooterpanelLabel.Text = 'Footer panel: This will be embedded at the bottom of the tab pages when the app is running';
            app.SaveChangesButton = uibutton(app.NDIDatasetWizardUIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.MainGridLayout = uigridlayout(app.NDIDatasetWizardUIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.IntroGridLayout = uigridlayout(app.IntroTab);
            app.GridLayout_Step0_C3 = uigridlayout(app.IntroGridLayout);
            app.GetStartedButton = uibutton(app.GridLayout_Step0_C3, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.GridLayout_Step0_C2 = uigridlayout(app.IntroGridLayout);
            app.IntroductionTextLabel = uilabel(app.GridLayout_Step0_C2);
            app.IntroductionTextLabel.Text = {''; 'We''re excited to have you here. This is your upload form, where you can effortlessly share your data with us. Over the next few pages, we''ll guide you through the process of ingesting your valuable data. Our goal is to make this as seamless as possible, ensuring that your information is accurately processed and ready for analysis. '; ''; 'If you ever need assistance with any of the form elements, simply hover over the respective item to access helpful information. For any further queries, shoot us an email at info@walthamdatascience.com and we''ll get right back to you! Thank you for choosing our app to help you manage your data. Let''s get started on this journey together!'};
            app.IntroLabel = uilabel(app.IntroGridLayout);
            app.IntroLabel.Text = {'Welcome to the NDI Cloud''s Upload Tool!'; ''};
            app.DatasetOverviewTab = uitab(app.TabGroup);
            app.DatasetOverviewTab.Title = 'Dataset Overview';
            app.DatasetOverviewGridLayout = uigridlayout(app.DatasetOverviewTab);
            app.DatasetInformationPanel = uipanel(app.DatasetOverviewGridLayout);
            app.GridLayout = uigridlayout(app.DatasetInformationPanel);
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.AbstractTextArea = uitextarea(app.GridLayout3);
            app.AbstractTextArea.ValueChangedFcn = createCallbackFcn(app, @AbstractValueChanged, true);
            app.AbstractLabel = uilabel(app.GridLayout3);
            app.AbstractLabel.Text = 'Abstract';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.ShortNameEditField = uieditfield(app.GridLayout2, 'text');
            app.ShortNameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetShortNameValueChanged, true);
            app.ShortNameEditField.Tooltip = {'If multiple, separate by comma '};
            app.DatasetBranchTitleEditField = uieditfield(app.GridLayout2, 'text');
            app.DatasetBranchTitleEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetBranchTitleValueChanged, true);
            app.DatasetBranchTitleLabel = uilabel(app.GridLayout2);
            app.DatasetBranchTitleLabel.Text = 'Dataset Branch Title';
            app.DatasetShortNameLabel = uilabel(app.GridLayout2);
            app.DatasetShortNameLabel.Text = 'Dataset Short Name';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.CommentsDetailsLabel = uilabel(app.GridLayout4);
            app.CommentsDetailsLabel.Text = 'Comments/Details';
            app.CommentsDetailsTextArea = uitextarea(app.GridLayout4);
            app.CommentsDetailsTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentsDetailsValueChanged, true);
            app.CommentsDetailsTextArea.Tooltip = {'Anything else you''d like us to know about your upload? Note: This field is not public facing.'};
            app.DatasetInformationLabel = uilabel(app.DatasetOverviewGridLayout);
            app.DatasetInformationLabel.Text = 'Dataset Information';
            app.AuthorsTab = uitab(app.TabGroup);
            app.AuthorsTab.Title = 'Authors';
            app.AuthorsGridLayout = uigridlayout(app.AuthorsTab);
            app.AuthorMainPanel = uipanel(app.AuthorsGridLayout);
            app.AuthorMainPanelGridLayout = uigridlayout(app.AuthorMainPanel);
            app.AuthorContentRightGridLayout = uigridlayout(app.AuthorMainPanelGridLayout);
            app.AuthorRoleLabel = uilabel(app.AuthorContentRightGridLayout);
            app.AuthorRoleLabel.Text = 'Author Role';
            app.AuthorRoleTree = uitree(app.AuthorContentRightGridLayout, 'checkbox');
            app.AuthorRoleTree.Tooltip = {'An author can have multiple roles'};
            app.stAuthorNode = uitreenode(app.AuthorRoleTree);
            app.stAuthorNode.Text = '1st Author';
            app.CustodianNode = uitreenode(app.AuthorRoleTree);
            app.CustodianNode.Text = 'Custodian';
            app.CorrespondingNode = uitreenode(app.AuthorRoleTree);
            app.CorrespondingNode.Text = 'Corresponding';
            % Assign Checked Nodes
            app.AuthorRoleTree.CheckedNodesChangedFcn = createCallbackFcn(app, @AuthorRoleTreeCheckedNodesChanged, true);
            app.AffiliationsListBoxLabel = uilabel(app.AuthorContentRightGridLayout);
            app.AffiliationsListBoxLabel.Text = 'Affiliations/Institutes';
            app.AffiliationListBoxGridLayout = uigridlayout(app.AuthorContentRightGridLayout);
            app.AffiliationTable = uitable(app.AffiliationListBoxGridLayout);
            app.AffiliationTable.ColumnName = {'Name'};
            app.AffiliationTable.RowName = {};
            app.AffiliationListBoxButtonGridLayout = uigridlayout(app.AffiliationListBoxGridLayout);
            app.MoveAffiliationDownButton = uibutton(app.AffiliationListBoxButtonGridLayout, 'push');
            app.MoveAffiliationDownButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'up.png');
            app.MoveAffiliationDownButton.Text = '';
            app.MoveAffiliationUpButton = uibutton(app.AffiliationListBoxButtonGridLayout, 'push');
            app.MoveAffiliationUpButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'down.png');
            app.MoveAffiliationUpButton.Text = '';
            app.RemoveAffiliationButton = uibutton(app.AffiliationListBoxButtonGridLayout, 'push');
            app.RemoveAffiliationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAffiliationButtonPushed, true);
            app.RemoveAffiliationButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'minus.png');
            app.RemoveAffiliationButton.Text = '';
            app.AddAffiliationButton = uibutton(app.AffiliationListBoxButtonGridLayout, 'push');
            app.AddAffiliationButton.ButtonPushedFcn = createCallbackFcn(app, @AddAffiliationButtonPushed, true);
            app.AddAffiliationButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'plus.png');
            app.AddAffiliationButton.Text = '';
            app.AuthorContentCenterGridLayout = uigridlayout(app.AuthorMainPanelGridLayout);
            app.GivenNameEditFieldLabel = uilabel(app.AuthorContentCenterGridLayout);
            app.GivenNameEditFieldLabel.Text = 'Given Name';
            app.GivenNameEditField = uieditfield(app.AuthorContentCenterGridLayout, 'text');
            app.GivenNameEditField.ValueChangedFcn = createCallbackFcn(app, @GivenNameEditFieldValueChanged, true);
            app.GivenNameEditField.ValueChangingFcn = createCallbackFcn(app, @GivenNameEditFieldValueChanging, true);
            app.FamilyNameEditFieldLabel = uilabel(app.AuthorContentCenterGridLayout);
            app.FamilyNameEditFieldLabel.Text = 'Family Name';
            app.FamilyNameEditField = uieditfield(app.AuthorContentCenterGridLayout, 'text');
            app.FamilyNameEditField.ValueChangedFcn = createCallbackFcn(app, @FamilyNameEditFieldValueChanged, true);
            app.FamilyNameEditField.ValueChangingFcn = createCallbackFcn(app, @FamilyNameEditFieldValueChanging, true);
            app.DigitalIdentifierORCIDLabel = uilabel(app.AuthorContentCenterGridLayout);
            app.DigitalIdentifierORCIDLabel.Text = 'Digital Identifier (ORCID)';
            app.AuthorOrcidGridLayout = uigridlayout(app.AuthorContentCenterGridLayout);
            app.DigitalIdentifierEditField = uieditfield(app.AuthorOrcidGridLayout, 'text');
            app.DigitalIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierEditFieldValueChanged, true);
            app.SearchOrcidButton = uibutton(app.AuthorOrcidGridLayout, 'push');
            app.SearchOrcidButton.ButtonPushedFcn = createCallbackFcn(app, @SearchOrcidButtonPushed, true);
            app.SearchOrcidButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearchOrcidButton.Text = '';
            app.AuthorEmailEditField = uieditfield(app.AuthorContentCenterGridLayout, 'text');
            app.AuthorEmailEditField.ValueChangedFcn = createCallbackFcn(app, @AuthorEmailEditFieldValueChanged, true);
            app.AuthorEmailEditFieldLabel = uilabel(app.AuthorContentCenterGridLayout);
            app.AuthorEmailEditFieldLabel.Text = 'Author Email';
            app.AdditionalDetailsTextAreaLabel = uilabel(app.AuthorContentCenterGridLayout);
            app.AdditionalDetailsTextAreaLabel.Text = 'Additional Details';
            app.AdditionalDetailsTextArea = uitextarea(app.AuthorContentCenterGridLayout);
            app.AdditionalDetailsTextArea.ValueChangedFcn = createCallbackFcn(app, @AdditionalDetailsValueChanged, true);
            app.AdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about this author? Note: This field is not public facing.'};
            app.AuthorContentLeftGridLayout = uigridlayout(app.AuthorMainPanelGridLayout);
            app.AuthorListBoxGridLayout = uigridlayout(app.AuthorContentLeftGridLayout);
            app.AuthorListBoxButtonGridLayout = uigridlayout(app.AuthorListBoxGridLayout);
            app.MoveAuthorDownButton = uibutton(app.AuthorListBoxButtonGridLayout, 'push');
            app.MoveAuthorDownButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'down.png');
            app.MoveAuthorDownButton.Text = '';
            app.MoveAuthorUpButton = uibutton(app.AuthorListBoxButtonGridLayout, 'push');
            app.MoveAuthorUpButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'up.png');
            app.MoveAuthorUpButton.Text = '';
            app.RemoveAuthorButton = uibutton(app.AuthorListBoxButtonGridLayout, 'push');
            app.RemoveAuthorButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAuthorButtonPushed, true);
            app.RemoveAuthorButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'minus.png');
            app.RemoveAuthorButton.Text = '';
            app.AddAuthorButton = uibutton(app.AuthorListBoxButtonGridLayout, 'push');
            app.AddAuthorButton.ButtonPushedFcn = createCallbackFcn(app, @AddAuthorButtonPushed2, true);
            app.AddAuthorButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'plus.png');
            app.AddAuthorButton.Text = '';
            app.AuthorListBox = uilistbox(app.AuthorListBoxGridLayout);
            app.AuthorListBox.Items = {};
            app.AuthorListBox.ValueChangedFcn = createCallbackFcn(app, @AuthorListBoxValueChanged, true);
            app.AuthorListBox.Value = {};
            app.AuthorListBoxLabel = uilabel(app.AuthorContentLeftGridLayout);
            app.AuthorListBoxLabel.Text = 'Create authors and fill out their details';
            app.AuthorDetailsLabel = uilabel(app.AuthorsGridLayout);
            app.AuthorDetailsLabel.Text = 'Author Details';
            app.DatasetDetailsTab = uitab(app.TabGroup);
            app.DatasetDetailsTab.Title = 'Dataset Details';
            app.DatasetDetailsGridLayout = uigridlayout(app.DatasetDetailsTab);
            app.DatasetDetailsPanel = uipanel(app.DatasetDetailsGridLayout);
            app.ReleaseDateDatePickerLabel = uilabel(app.DatasetDetailsPanel);
            app.ReleaseDateDatePickerLabel.Text = 'Release Date';
            app.ReleaseDateDatePicker = uidatepicker(app.DatasetDetailsPanel);
            app.ReleaseDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ReleaseDateValueChanged, true);
            app.LicenseDropDownLabel = uilabel(app.DatasetDetailsPanel);
            app.LicenseDropDownLabel.Text = 'License';
            app.LicenseDropDown = uidropdown(app.DatasetDetailsPanel);
            app.LicenseDropDown.Items = {'CC BY 4.0', 'CC BY-SA 4.0', 'CC BY-NC 4.0', 'CC BY-NC-SA 4.0', 'CC BY-ND 4.0', 'CC BY-NC-ND 4.0'};
            app.LicenseDropDown.ValueChangedFcn = createCallbackFcn(app, @LicenseDropDownValueChanged, true);
            app.LicenseDropDown.Tooltip = {'Choose from one of our approved licenses'};
            app.LicenseDropDown.Value = 'CC BY 4.0';
            app.VersionIdentifierEditFieldLabel = uilabel(app.DatasetDetailsPanel);
            app.VersionIdentifierEditFieldLabel.Text = 'Version Identifier';
            app.VersionIdentifierEditField = uieditfield(app.DatasetDetailsPanel, 'text');
            app.VersionIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @VersionIdentifierValueChanged, true);
            app.VersionIdentifierEditField.Tooltip = {'If multiple, separate by comma '};
            app.FullDocumentationEditFieldLabel = uilabel(app.DatasetDetailsPanel);
            app.FullDocumentationEditFieldLabel.Text = 'Full Documentation';
            app.FullDocumentationEditField = uieditfield(app.DatasetDetailsPanel, 'text');
            app.FullDocumentationEditField.ValueChangedFcn = createCallbackFcn(app, @FullDocumentationValueChanged, true);
            app.FullDocumentationEditField.Tooltip = {'If multiple, separate by comma '};
            app.VersionInnovationEditFieldLabel = uilabel(app.DatasetDetailsPanel);
            app.VersionInnovationEditFieldLabel.Text = 'Version Innovation';
            app.VersionInnovationEditField = uieditfield(app.DatasetDetailsPanel, 'text');
            app.VersionInnovationEditField.ValueChangedFcn = createCallbackFcn(app, @VersionInnovationValueChanged, true);
            app.VersionInnovationEditField.Tooltip = {'If multiple, separate by comma '};
            app.FundingEditFieldLabel = uilabel(app.DatasetDetailsPanel);
            app.FundingEditFieldLabel.Text = 'Funding';
            app.FundingEditField = uieditfield(app.DatasetDetailsPanel, 'text');
            app.FundingEditField.ValueChangedFcn = createCallbackFcn(app, @FundingValueChanged, true);
            app.AddRelatedPublicationButton = uibutton(app.DatasetDetailsPanel, 'push');
            app.AddRelatedPublicationButton.ButtonPushedFcn = createCallbackFcn(app, @AddRelatedPublicationButtonPushed, true);
            app.AddRelatedPublicationButton.Text = 'Add';
            app.RelatedPublicationUITable = uitable(app.DatasetDetailsPanel);
            app.RelatedPublicationUITable.ColumnName = {'Publication'; 'DOI'; 'PMID'; 'PMCID'};
            app.RelatedPublicationUITable.RowName = {};
            app.RelatedPublicationUITable.ColumnEditable = [true true true true];
            app.RelatedPublicationUITable.CellEditCallback = createCallbackFcn(app, @RelatedPublicationCellEdit, true);
            app.RelatedPublicationsLabel = uilabel(app.DatasetDetailsPanel);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.UITable = uitable(app.DatasetDetailsPanel);
            app.UITable.ColumnName = {'Funder'; 'Award Title'; 'Award Number'};
            app.UITable.RowName = {};
            app.DatasetDetailsLabel = uilabel(app.DatasetDetailsGridLayout);
            app.DatasetDetailsLabel.Text = 'Dataset Details';
            app.ExperimentDetailsTab = uitab(app.TabGroup);
            app.ExperimentDetailsTab.Title = 'Experiment Details';
            app.ExperimentDetailsGridLayout = uigridlayout(app.ExperimentDetailsTab);
            app.ExperimentDetailsPanel = uipanel(app.ExperimentDetailsGridLayout);
            app.TechniquesEmployedTree = uitree(app.ExperimentDetailsPanel, 'checkbox');
            app.TechniquesEmployedTree.Tooltip = {''};
            % Assign Checked Nodes
            app.TechniquesEmployedTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TechniqueTreeCheckedNodesChanged, true);
            app.TechniquesEmployedLabel_2 = uilabel(app.ExperimentDetailsPanel);
            app.TechniquesEmployedLabel_2.Text = 'Techniques Employed';
            app.DataTypeLabel = uilabel(app.ExperimentDetailsPanel);
            app.DataTypeLabel.Text = 'Data Type';
            app.DataTypeTree = uitree(app.ExperimentDetailsPanel, 'checkbox');
            app.DataTypeTree.Tooltip = {''};
            app.RawNode = uitreenode(app.DataTypeTree);
            app.RawNode.Text = 'Raw';
            app.DerivedNode = uitreenode(app.DataTypeTree);
            app.DerivedNode.Text = 'Derived';
            app.SimulatedNode = uitreenode(app.DataTypeTree);
            app.SimulatedNode.Text = 'Simulated';
            % Assign Checked Nodes
            app.DataTypeTree.CheckedNodesChangedFcn = createCallbackFcn(app, @DataTypeTreeCheckedNodesChanged, true);
            app.ExperimentalApproachTree = uitree(app.ExperimentDetailsPanel, 'checkbox');
            app.ExperimentalApproachTree.Tooltip = {''};
            % Assign Checked Nodes
            app.ExperimentalApproachTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ExperimentTreeCheckedNodesChanged, true);
            app.ExperimentalApproachLabel = uilabel(app.ExperimentDetailsPanel);
            app.ExperimentalApproachLabel.Text = 'Experimental Approach';
            app.SelectTechniqueCategoryDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.SelectTechniqueCategoryDropDownLabel.Text = 'Select Technique Category';
            app.SelectTechniqueCategoryDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.SelectTechniqueCategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTechniqueCategoryDropDownValueChanged, true);
            app.SelectTechniqueLabel = uilabel(app.ExperimentDetailsPanel);
            app.SelectTechniqueLabel.Text = 'Select Technique';
            app.SelectTechniqueDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.SelectTechniqueDropDown.Editable = 'on';
            app.SelectedTechniquesListBoxLabel = uilabel(app.ExperimentDetailsPanel);
            app.SelectedTechniquesListBoxLabel.Text = 'Selected Techniques';
            app.SelectedTechniquesListBox = uilistbox(app.ExperimentDetailsPanel);
            app.SelectedTechniquesListBox.Items = {};
            app.SelectedTechniquesListBox.Value = {};
            app.AddTechniqueButton = uibutton(app.ExperimentDetailsPanel, 'push');
            app.AddTechniqueButton.ButtonPushedFcn = createCallbackFcn(app, @AddTechniqueButtonPushed, true);
            app.AddTechniqueButton.Text = 'Add';
            app.ExperimentDetailsLabel = uilabel(app.ExperimentDetailsGridLayout);
            app.ExperimentDetailsLabel.Text = 'Experiment Details';
            app.SubjectInfoTab = uitab(app.TabGroup);
            app.SubjectInfoTab.Title = 'Subject Info';
            app.SubjectInfoGridLayout = uigridlayout(app.SubjectInfoTab);
            app.SubjectInfoPanel = uipanel(app.SubjectInfoGridLayout);
            app.GridLayout16 = uigridlayout(app.SubjectInfoPanel);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.Panel_6 = uipanel(app.GridLayout17);
            app.BiologicalSexListBox = uilistbox(app.Panel_6);
            app.BiologicalSexListBox.Items = {'asexual multicellular organism', 'female organism', 'male organism', 'hermaphroditic organism'};
            app.BiologicalSexListBox.ValueChangedFcn = createCallbackFcn(app, @BiologicalSexListBoxValueChanged, true);
            app.BiologicalSexListBox.ClickedFcn = createCallbackFcn(app, @BiologicalSexListBoxClicked, true);
            app.BiologicalSexListBox.Value = 'asexual multicellular organism';
            app.BiologicalSexLabel = uilabel(app.Panel_6);
            app.BiologicalSexLabel.Text = 'Biological Sex';
            app.AssignBiologicalSexButton = uibutton(app.Panel_6, 'push');
            app.AssignBiologicalSexButton.ButtonPushedFcn = createCallbackFcn(app, @AssignBiologicalSexButtonPushed, true);
            app.AssignBiologicalSexButton.Text = 'Assign';
            app.BiologicalSexClearButton = uibutton(app.Panel_6, 'push');
            app.BiologicalSexClearButton.ButtonPushedFcn = createCallbackFcn(app, @BiologicalSexClearButtonPushed, true);
            app.BiologicalSexClearButton.Text = 'CLEAR';
            app.Panel_7 = uipanel(app.GridLayout17);
            app.AssignSpeciesButton = uibutton(app.Panel_7, 'push');
            app.AssignSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AssignSpeciesButtonPushed, true);
            app.AssignSpeciesButton.Text = 'Assign';
            app.SpeciesListBox = uilistbox(app.Panel_7);
            app.SpeciesListBox.Items = {};
            app.SpeciesListBox.ValueChangedFcn = createCallbackFcn(app, @SpeciesListBoxValueChanged, true);
            app.SpeciesListBox.ClickedFcn = createCallbackFcn(app, @SpeciesListBoxClicked, true);
            app.SpeciesListBox.Value = {};
            app.SpeciesLabel_2 = uilabel(app.Panel_7);
            app.SpeciesLabel_2.Text = 'Species';
            app.SpeciesClearButton = uibutton(app.Panel_7, 'push');
            app.SpeciesClearButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesClearButtonPushed, true);
            app.SpeciesClearButton.Text = 'CLEAR';
            app.SpeciesEditField = uieditfield(app.Panel_7, 'text');
            app.AddSpeciesButton = uibutton(app.Panel_7, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpeciesButtonPushed, true);
            app.AddSpeciesButton.Text = 'Add';
            app.Panel_8 = uipanel(app.GridLayout17);
            app.AssignStrainButton = uibutton(app.Panel_8, 'push');
            app.AssignStrainButton.Text = 'Assign';
            app.StrainLabel_3 = uilabel(app.Panel_8);
            app.StrainLabel_3.Text = 'Strain';
            app.StrainClearButton = uibutton(app.Panel_8, 'push');
            app.StrainClearButton.ButtonPushedFcn = createCallbackFcn(app, @StrainClearButtonPushed, true);
            app.StrainClearButton.Text = 'CLEAR';
            app.StrainListBox = uilistbox(app.Panel_8);
            app.StrainListBox.Items = {'conditional knockin ready', 'conditional knockout ready', 'conditional ready', 'knockin', 'knockout', 'transgenic', 'wildtype'};
            app.StrainListBox.ValueChangedFcn = createCallbackFcn(app, @StrainListBoxValueChanged, true);
            app.StrainListBox.ClickedFcn = createCallbackFcn(app, @StrainListBoxClicked, true);
            app.StrainListBox.Value = 'conditional knockin ready';
            app.UITableSubject = uitable(app.GridLayout16);
            app.UITableSubject.ColumnName = {'Subject'; 'Biological Sex'; 'Species'; 'Strain'};
            app.UITableSubject.RowName = {};
            app.SubjectInfoLabel = uilabel(app.SubjectInfoGridLayout);
            app.SubjectInfoLabel.Text = 'Subject Info';
            app.ProbeInfoTab = uitab(app.TabGroup);
            app.ProbeInfoTab.Title = 'Probe Info';
            app.ProbeInfoGridLayout = uigridlayout(app.ProbeInfoTab);
            app.ProbeInfoPanel = uipanel(app.ProbeInfoGridLayout);
            app.UITableProbe = uitable(app.ProbeInfoPanel);
            app.UITableProbe.ColumnName = {'Probe Name'; 'Probe type'; 'Status'};
            app.UITableProbe.RowName = {};
            app.UITableProbe.ColumnEditable = [false false false];
            app.UITableProbe.DoubleClickedFcn = createCallbackFcn(app, @UITableProbeDoubleClicked, true);
            app.UITableProbe.Multiselect = 'off';
            app.ProbeInfoLabel = uilabel(app.ProbeInfoGridLayout);
            app.ProbeInfoLabel.Text = 'Probe Info';
            app.SubmitTab = uitab(app.TabGroup);
            app.SubmitTab.Title = 'Submit';
            app.SubmitGridLayout = uigridlayout(app.SubmitTab);
            app.SubmitFooterGridLayout = uigridlayout(app.SubmitGridLayout);
            app.SubmitButton = uibutton(app.SubmitFooterGridLayout, 'push');
            app.SubmitButton.Text = 'Submit';
            app.SubmitPanelGridLayout = uigridlayout(app.SubmitGridLayout);
            app.SubmissionStatusPanel = uipanel(app.SubmitPanelGridLayout);
            app.ErrorTextAreaLabel = uilabel(app.SubmissionStatusPanel);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.ErrorTextArea = uitextarea(app.SubmissionStatusPanel);
            app.SubmissionDescriptionLabel = uilabel(app.SubmitPanelGridLayout);
            app.SubmissionDescriptionLabel.Text = 'If you have filled out all the information for this dataset, please go ahead with the submission. If you want to review your information one last time, feel free to look through each of the pages again';
            app.SubmitLabel = uilabel(app.SubmitGridLayout);
            app.SubmitLabel.Text = 'Review and Submit Data';
            app.FooterPanel = uipanel(app.NDIDatasetWizardUIFigure);
            app.GridLayout5 = uigridlayout(app.FooterPanel);
            app.PreviousButton = uibutton(app.GridLayout5, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout5, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.NDIDatasetWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetUploadApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NDIDatasetWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NDIDatasetWizardUIFigure)
        end
    end
end