classdef LoginForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        LoginButton             matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        EmailEditFieldLabel     matlab.ui.control.Label
        EmailEditField          matlab.ui.control.EditField
        LoginLabel              matlab.ui.control.Label
        Tab2                    matlab.ui.container.Tab
        Button                  matlab.ui.control.Button
        ExistingDatasetListBox  matlab.ui.control.ListBox
        EmailEditFieldLabel_2   matlab.ui.control.Label
        DatasetsLabel           matlab.ui.control.Label
    end

    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user login or cancels
        FinishState (1,1) string % "Authorized" or "Cancel"
        loginInformation
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        % A struct for storing input form apps, like the Author form. Used
        % to keep external forms/figures in memory, but hidden
        UIForm (1,1) struct
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
    
        function setLoginDetails(app, loginInformation)
            if isfield(loginInformation, "email")
                app.EmailEditField = loginInformation.email;
            end
            if isfield(loginInformation, "password")
                app.PasswordEditField = loginInformation.password;
            end
        end

        function loginInformation = getLoginDetails(app)
            loginInformation.email = app.EmailEditField;
            loginInformation.password = app.PasswordEditField;
            app.loginInformation = loginInformation;
        end

        function reset(app)
            app.EmailEditField.Value = '';
            app.PasswordEditField.Value = '';
            app.FinishState = "";
        end

      
        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function loginButtonPushed(app, event)
            app.getLoginDetails(app);
            
            if ~(isfield(app.loginInformation, "email") && isfield(app.loginInformation, "password"))
                errMessage = sprintf('Please fill in your Email and password.');
                uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Empty Email or Password');
            end
           
            try
                progressdlg = uiprogressdlg(app.UIFigure, ...
                    "Indeterminate", "on", "Message", "Logging in...", ...
                    "Title", "Please Wait");
                [status, auth_token, organization_id] = ndi.cloud.auth.login(app.loginInformation.email, app.loginInformation.password);
                
                if status
                    progressdlg.Indeterminate = "off";
                    progressdlg.Message = "Logged In";
                    delete(progressdlg)
                    app.loginInformation.auth_token = auth_token;
                    app.organizationId.auth_token = organization_id;
                    [status, response, datasets] = ndi.cloud.datasets.get_organizations(organization_id, auth_token);
                    if status
                        error("Unknown error occured. Please check the log.");
                    elseif numel(datasets) >= 1
                        msg = sprintf('Existing dataset was found in your organization. Do you wish to update existing dataset or create a new one?');
                        answer = uiconfirm(app.UIFigure, msg, "Create new Dataset", "Options", {'Create', 'Update'}, 'CancelOption', 'Create', 'Interpreter', 'html');
                        if strcmp(answer, 'Create')
                            app.FinishState = "authorized";
                            app.UIFigureCloseRequest();
                        else
                            
                        end
                    else
                        app.FinishState = "authorized";
                        app.UIFigureCloseRequest()
                    end
                     
                else
                    progressdlg.Indeterminate = "off";
                    delete(progressdlg)
                    errMessage = sprintf('Invalid Email or passwrod.');
                    uialert(app.NDIDatasetWizardUIFigure, errMessage, 'Please enter valid Email and password');
                end 
            catch ME
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.LoginLabel = uilabel(app.Tab);
            app.LoginLabel.Text = 'Login';
            app.EmailEditField = uieditfield(app.Tab, 'text');
            app.EmailEditField.Tooltip = {'If multiple, separate by comma '};
            app.EmailEditFieldLabel = uilabel(app.Tab);
            app.EmailEditFieldLabel.Text = 'Email';
            app.PasswordEditFieldLabel = uilabel(app.Tab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Tab, 'text');
            app.PasswordEditField.Tooltip = {'If multiple, separate by comma '};
            app.LoginButton = uibutton(app.Tab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginButtonPushed, true);
            app.LoginButton.Text = 'Log in';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.DatasetsLabel = uilabel(app.Tab2);
            app.DatasetsLabel.Text = 'Datasets';
            app.EmailEditFieldLabel_2 = uilabel(app.Tab2);
            app.EmailEditFieldLabel_2.Text = 'Which dataset are you updating?';
            app.ExistingDatasetListBox = uilistbox(app.Tab2);
            app.ExistingDatasetListBox.Items = {};
            app.ExistingDatasetListBox.Value = {};
            app.Button = uibutton(app.Tab2, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end