classdef PipetteForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        PIPETTELabel                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        InternalDiameterUnitListBoxLabel  matlab.ui.control.Label
        NoofElectrodesEditFieldLabel    matlab.ui.control.Label
        CompleteCheckBox                matlab.ui.control.CheckBox
        ManufacturerEditField           matlab.ui.control.EditField
        ManufactureLabel                matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DigitalIdentifierDropDownLabel  matlab.ui.control.Label
        DigitalIdentifierDropDown       matlab.ui.control.DropDown
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        InternalDiameterEditField       matlab.ui.control.EditField
        InternalDiameterEditFieldLabel  matlab.ui.control.Label
        InternalDiameterUnitListBox     matlab.ui.control.ListBox
    end

         
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
        Pipette
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        UIForm
        Manufacturer
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end

    methods (Access = public)
        % % Open (external app) form where user can enter affiliation details
        % function openManufacturerForm(app, manufacturerName)
        % %openManufacturerForm Open Affiliation form where user can enter Affiliation details
        %     if ~isfield(app.UIForm, 'Manufacturer')
        %         app.UIForm.Manufacturer = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
        %     else
        %         app.UIForm.Manufacturer.Visible = 'on'; % Make the form visible
        %     end
        % 
        %     if nargin > 1 && ~isempty(manufacturerName)
        %         app.UIForm.Manufacturer.setName(manufacturerName);
        %     end
        % 
        %     app.UIForm.Manufacturer.waitfor(); % Wait for user to proceed
        % 
        %     % Get user-inputs from form
        %     S = app.UIForm.Manufacturer.getAffiliationDetails();
        % 
        %     % Update data in table if user pressed save.
        %     mode = app.UIForm.Manufacturer.FinishState;
        %     if mode == "Save"
        %         app.ManufacturerEditField.Value = S.AffiliationName;
        %     end
        %     app.Manufacturer = S;
        % 
        %     app.UIForm.Manufacturer.reset()
        %     app.UIForm.Manufacturer.Visible = 'off'; % Hide the form (for later reuse)
        % end
        
        % Open (external app) form where user can enter manufacturer details
        function S = openOrganizationForm(app, organizationInfo, organizationIndex)
        %openOrganizationForm Open Organization form where user can enter organization details

            progressdlg = uiprogressdlg(app.UIFigure, ...
                    "Indeterminate", "on", "Message", "Opening Organization Form, please wait...", ...
                    "Title", "Please Wait");

            if ~isfield(app.UIForm, 'Organization')
                app.UIForm.Organization = ndi.database.metadata_app.Apps.OrganizationForm(); % Create the form
            else
                app.UIForm.Organization.Visible = 'on'; % Make the form visible
            end
            progressdlg.Message = "Please fill in the organization information";

            if nargin > 1 && ~isempty(organizationInfo)
                % Update form information if we are editing an organization
                app.UIForm.Organization.setOrganizationDetails(organizationInfo);
            end

            app.UIForm.Organization.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Organization.getOrganizationDetails();

            progressdlg.Indeterminate = "off";
            progressdlg.Message = "Saving manufacturer...";
            pause(1.5)
            delete(progressdlg)
            
            % Update data in table if user pressed save.
            mode = app.UIForm.Organization.FinishState;
            if mode == "Save"
                if nargin > 2 && ~isempty(organizationIndex)
                    app.insertOrganization(S, organizationIndex)
                else
                    app.insertOrganization(S);
                end
                app.ManufacturerEditField.Value = app.Manufacturer.fullName;
            else
                S = struct.empty;
                app.ManufacturerEditField.Value = '';
            end

            app.UIForm.Organization.reset()
            app.UIForm.Organization.Visible = 'off'; % Hide the form (for later reuse)

            if ~nargout
                clear S
            end
        end

        function insertOrganization(app, S, insertIndex)

            if nargin < 3 || isempty(insertIndex)
                insertIndex = numel(app.Manufacturer) + 1;
            end

            if isempty(app.Manufacturer)
                app.Manufacturer = S;
            else
                app.Manufacturer(insertIndex) = S;
            end
            
            % Update organization dropdown
            organizationNames = {app.Manufacturer.fullName};
            % app.OrganizationDropDown.Items = organizationNames;
            app.saveOrganizationInstances()
        end

        function loadOrganizations(app)
            import ndi.database.metadata_app.fun.loadUserInstances
            app.Organizations = loadUserInstances('manufacturer_organization');
        end

        function saveOrganizationInstances(app)
            import ndi.database.metadata_app.fun.saveUserInstances
            organizationInstances = app.Manufacturer;
            saveUserInstances('manufacturer_organization', organizationInstances)
        end

        function selected = digitalIdentifierSelected(app)
            if strcmp(app.DigitalIdentifierDropDown.Value, "Select type")
                selected = 0;
            else
                selected = 1;
            end
        end

        function entered = internalDiameterEntered(app)
            if isempty(app.InternalDiameterEditField.Value)
                entered = 0;
            else
                entered = 1;
            end
        end
    
        function setProbeDetails(app, pipette)
            app.Pipette = pipette;
            app.NameEditField.Value = pipette.Name;
            app.DescriptionEditField.Value = pipette.Description;
            [type, value] = pipette.getDigitalIdentifier();
            app.DigitalIdentifierDropDown.Value = type;
            app.DigitalIdentifierEditField.Value = value;
            app.Manufacturer = pipette.Manufacturer;
            app.ManufacturerEditField.Value = pipette.getManufacturerName();

            app.CompleteCheckBox.Value = pipette.Complete;
            app.InternalDiameterEditField.Value = pipette.InternalDiameter;
            app.InternalDiameterUnitListBox.Value = pipette.InternalDiameterUnit;
        end

        function pipette = getProbeDetails(app)
            if isempty(app.Pipette)
                pipette = ndi.database.metadata_app.class.Pipette();
            else
                pipette = app.Pipette;
            end
            pipette.updateProperty("Name", app.NameEditField.Value);
            pipette.updateProperty("Description", app.DescriptionEditField.Value);
            digitalIdentifier = struct('Type', app.DigitalIdentifierDropDown.Value, 'Value', app.DigitalIdentifierEditField.Value);
            pipette.updateProperty("DigitalIdentifier", digitalIdentifier);
            pipette.updateProperty("Manufacturer", app.Manufacturer);
            pipette.updateProperty("Complete", app.CompleteCheckBox.Value);
            pipette.updateProperty("InternalDiameter", app.InternalDiameterEditField.Value);
            pipette.updateProperty("InternalDiameterUnit", app.InternalDiameterUnitListBox.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.DigitalIdentifierEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.InternalDiameterEditField.Value = '';
            app.InternalDiameterUnitListBox.Value = app.InternalDiameterUnitListBox.Items{1};
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            ndi.database.metadata_app.fun.loadInstancesToListBox(app.InternalDiameterUnitListBox, 'UnitOfMeasurement', {'meter'});
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pipette = app.getProbeDetails();
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest()
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            pipette = app.getProbeDetails();
            if ~pipette.intrinsicDiameterCheck()
                uialert(app.UIFigure, 'Please select a unit for intrinsic diameter or delete the value enetered in the instrinsic diameter field.', 'Empty Intrinsic diameter Unit');
            else
                app.FinishState = "Save";
                app.UIFigureCloseRequest()
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Value changed function: ManufacturerEditField
        function ManufacturerValueChanged(app, event)
            value = app.ManufacturerEditField.Value;
            S.fullName = value;

            app.openOrganizationForm(S);
        end

        % Value changed function: InternalDiameterUnitListBox
        function InternalDiameterUnitListBoxValueChanged(app, event)
            value = app.InternalDiameterUnitListBox.Value;
            if ~(app.internalDiameterEntered())
                app.InternalDiameterUnitListBox.Value = app.InternalDiameterUnitListBox.Items{1};
                uialert(app.UIFigure, 'Please enter value for internal diameter', 'Empty internal diameter');
            end
        end

        % Value changed function: DigitalIdentifierEditField
        function DigitalIdentifierValueChanged(app, event)
            value = app.DigitalIdentifierEditField.Value;
            if ~(app.digitalIdentifierSelected())
                app.DigitalIdentifierEditField.Value = '';
                uialert(app.UIFigure, 'Please select the digital identifier type', 'Empty Digital Identifier Type');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Edit Pipette Information';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.InternalDiameterUnitListBox = uilistbox(app.GridLayout2);
            app.InternalDiameterUnitListBox.Items = {'load'};
            app.InternalDiameterUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InternalDiameterUnitListBoxValueChanged, true);
            app.InternalDiameterUnitListBox.Value = 'load';
            app.InternalDiameterEditFieldLabel = uilabel(app.GridLayout2);
            app.InternalDiameterEditFieldLabel.Text = 'Internal Diameter';
            app.InternalDiameterEditField = uieditfield(app.GridLayout2, 'text');
            app.InternalDiameterEditField.Tooltip = {'Optional'};
            app.NameEditFieldLabel = uilabel(app.GridLayout2);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout2, 'text');
            app.NameEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.GridLayout2);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.GridLayout2, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierDropDown = uidropdown(app.GridLayout2);
            app.DigitalIdentifierDropDown.Items = {'Select type', 'ROI', 'RRID'};
            app.DigitalIdentifierDropDown.Placeholder = 'Select type';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.DigitalIdentifierDropDownLabel = uilabel(app.GridLayout2);
            app.DigitalIdentifierDropDownLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.GridLayout2);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.GridLayout2, 'text');
            app.DigitalIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierValueChanged, true);
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.ManufactureLabel = uilabel(app.GridLayout2);
            app.ManufactureLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.GridLayout2, 'text');
            app.ManufacturerEditField.ValueChangedFcn = createCallbackFcn(app, @ManufacturerValueChanged, true);
            app.CompleteCheckBox = uicheckbox(app.GridLayout2);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel = uilabel(app.GridLayout2);
            app.NoofElectrodesEditFieldLabel.Text = 'Complete';
            app.InternalDiameterUnitListBoxLabel = uilabel(app.GridLayout2);
            app.InternalDiameterUnitListBoxLabel.Text = 'Unit';
            app.PIPETTELabel = uilabel(app.GridLayout);
            app.PIPETTELabel.Text = 'PIPETTE';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipetteForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end